classdef GUI4_for_gy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RightPanel         matlab.ui.container.Panel
        TabGroup           matlab.ui.container.TabGroup
        Tab1               matlab.ui.container.Tab
        ImageAxes_1        matlab.ui.control.UIAxes
        TextArea_2         matlab.ui.control.TextArea
        Image              matlab.ui.control.Image
        hypometabolismTab  matlab.ui.container.Tab
        ImageAxes_2        matlab.ui.control.UIAxes
        projADTab          matlab.ui.container.Tab
        ImageAxes_3        matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        LeftPanel          matlab.ui.container.Panel
        LoadFileButton     matlab.ui.control.Button
        RunButton          matlab.ui.control.Button
        Labelzhanghao      matlab.ui.control.Label
        Labelmima          matlab.ui.control.Label
        SigninButton       matlab.ui.control.StateButton
        zhanghao           matlab.ui.control.EditField
        mima               matlab.ui.control.EditField
        message            matlab.ui.control.Label
    end

    
    properties (Access = public)
        loadfile % Description
        computing_state = load_run_state.load % the state to indicate if the computing the result
        dir1
        dir2
        dir3
        dir_state=exist_state.not_exist
    end
    
    
    methods (Access = public)
        
        function reset_axises(app)
            app.TabGroup.SelectedTab = app.Tab1;
            cla(app.ImageAxes_1);
            cla(app.ImageAxes_2);
            cla(app.ImageAxes_3);
            app.TextArea.Visible = 'off';
        end
        
        function is_direxist(app)
            dir_GLU=['D:\GUI','\Glucose_HypoMetabolism.tif'];dir_hypo=['D:\GUI','\hypometabolism.tif'];dir_proj=['D:\GUI','\projAD.tif']
            if exist(dir_GLU)&&exist(dir_hypo)&&exist(dir_proj)
                  app.dir1=dir_GLU;
                  app.dir2=dir_hypo;
                  app.dir3=dir_proj;
                  app.dir_state=exist_state.exist;
            end
        end
        
        function [im_1,im_2,im_3] = im_path(app)
             if app.dir_state==exist_state.not_exist
%                 pause(20);
                input_cf_dir = app.loadfile;
                run_dir = '/home/xlab/sSynapse_new_05142019_test';
        
                [result1, result2, result3] = bGvPet_be4_cgy(run_dir,input_cf_dir);
                app.TextArea_2.Visible="off";
                app.TextArea.Visible="on";
                
                    im1=imread(result1);
                    im2=imread(result2);
                    im3=imread(result3);
                else
                    im1=imread(app.dir1);
                    im2=imread(app.dir2);
                    im3=imread(app.dir3);
                    app.dir_state=exist_state.not_exist;
                    app.TextArea_2.Visible="off";
                    app.TextArea.Visible="on";
             end
             im_1=im1;
             im_2=im2;
             im_3=im3;
            
        end
        
        function  render_result(app,im1,im2,im3,WX,WY)
                SIZE_axes1=size(im1);
                X_axes1=SIZE_axes1(1,1);
                Y_axes1=SIZE_axes1(1,2);
                SIZE_axes2=size(im2);
                X_axes2=SIZE_axes2(1,1);
                Y_axes2=SIZE_axes2(1,2);
                SIZE_axes3=size(im3);
                X_axes3=SIZE_axes3(1,1);
                Y_axes3=SIZE_axes3(1,2);
                app.ImageAxes_1.Visible="off";
                app.ImageAxes_2.Visible="off";
                app.ImageAxes_3.Visible="off";
                axis(app.ImageAxes_1, 'image');
                axis(app.ImageAxes_2, 'image');
                axis(app.ImageAxes_3, 'image');
                app.Image.Visible="off";
                imagesc(app.ImageAxes_1,im1);
                imagesc(app.ImageAxes_2,im2);
                imagesc(app.ImageAxes_3,im3);
    
    %            设置axes位置和大小
               app.ImageAxes_1.Position=[round(WX/2-((Y_axes1*4*WY/5)/X_axes1)/2),round(WY/2-2*WY/5),round((Y_axes1*4*WY/5)/X_axes1),4*WY/5];
    %           禁止交互， enableDefaultInteractivity重新启用交互
               disableDefaultInteractivity(app.ImageAxes_1) ;
               app.ImageAxes_1.Toolbar.Visible="off";
               
    %            设置axes位置和大小
               ratio_image2 = 1.0;
               app.ImageAxes_2.Position=[round(WX/2-((Y_axes2*WY*ratio_image2)/X_axes2)/2),round(WY/2-WY*ratio_image2/2),round((Y_axes2*WY*ratio_image2)/X_axes2),WY*ratio_image2];
    %           禁止交互， enableDefaultInteractivity重新启用交互
               disableDefaultInteractivity(app.ImageAxes_2) ;
               app.ImageAxes_2.Toolbar.Visible="off";
               
    %            设置axes位置和大小
               app.ImageAxes_3.Position=[round(2*WX/3-((Y_axes3*4*WY/5)/X_axes3)/2),round(WY/2-2*WY/5),round((Y_axes3*4*WY/5)/X_axes3),4*WY/5];
               
               half_text_width = WY*2/5;
               app.TextArea.Position=[round(2*WX/3-((Y_axes3*4*WY/5)/X_axes3)/2-half_text_width),round(WY/2-WY/4),round(WY*2/5),WY/2];
               guidenote_for_result3 = {['PROCEDURE: An automated algorithm was used to transform the patient''s ' ...
                   'image into the dimensions of a standard brain, '...
                   'normalize the data for variations in whole brain measurements, '...
                   'compute a statistical map of significantly reduced glucose '...
                   'metabolism relative to 87 normal control subjects (mean age 64), '...
                   'and superimpose this map of significantly reduced glucose metabolism '...
                   'in 14 patient''s with Alzheimer''s dementia (mean age 64) '...
                   'and a spatially standardized, volume rendered MRI of the brain.'],
                   
                   ['FINDINGS: The figure below shows the outer and inner surface of the brain.'...
                   ' Colored areas show significantly reduced glucose metabolism in the patient; '...
                   'and the outlined areas indicate significantly reduced glucose metabolism in those with Alzheimer''s dementia.']};
               app.TextArea.Value=guidenote_for_result3;
    %           禁止交互， enableDefaultInteractivity重新启用交互
               disableDefaultInteractivity(app.ImageAxes_3) ;
               app.ImageAxes_3.Toolbar.Visible="off";
               app.computing_state = load_run_state.load;
        end
    end
    
    methods (Access = public)
        
        function [symbel, niifile] = isniifile(app)
            fidin = fopen(app.loadfile);
            %input_file_content = '';
            niifile = '';
            while ~feof(fidin)
                niifile = fgetl(fidin);
            end
            [filepath,name,ext] = fileparts(niifile);
            symbel = strcmpi(ext, '.nii');
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             FIG_size=app.UIFigure.Position;
            app.UIFigure.Color=[0.94,0.94,0.94];
            app.LeftPanel.Visible="off";
            app.RightPanel.Visible="off";
            WXfig=app.UIFigure.Position(1,3);
            WYfig=app.UIFigure.Position(1,4);
            app.zhanghao.Position=[round(WXfig/2-100),round(WYfig/2),200,30];
            app.Labelzhanghao.Position=[round(WXfig/2-180),round(WYfig/2),200,30];
            app.mima.Position=[round(WXfig/2-100),round(WYfig/2-50),200,30];
            app.Labelmima.Position=[round(WXfig/2-180),round(WYfig/2-50),200,30];
            app.SigninButton.Position=[round(WXfig/2-60),round(WYfig/2-100),120,30];
            app.message.Position=[round(WXfig/2-160),round(WYfig/2+50),300,30];
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            if (app.computing_state == load_run_state.load || app.computing_state == load_run_state.run_or_load)
                WXload=app.TabGroup.Position(1,3);
                WYload=app.TabGroup.Position(1,4);
                app.TextArea_2.Position=[WXload/2-250,WYload/2-250,500,500];
                
                %choose a file dir with a *txt extention
                
                %open the txt file and read a line as a string
                
                %if the string is file as format *nii, read it and display
                %info, else show invalid file.
                
                filterspec = {'*.txt'};
                [f, p] = uigetfile(filterspec);
                
                app.loadfile = [p f];
                
                % do nothing when app.loadfile is empty
                if ischar(p) && ischar(f)
                    reset_axises(app);
                    [symbel, niifile] = isniifile(app);
                    if symbel
                        app.computing_state = load_run_state.run_or_load;
                        info=spm_vol(niifile);
                        app.TextArea_2.Visible="on";
                        app.TextArea_2.HorizontalAlignment="left";
                        str = {'Load completed!','', 'Examination Type:','FDG','', 'Filename:' ,info.fname,'','Image size:',[num2str(info.dim(1)),'  ', num2str(info.dim(2)),'  ', num2str(info.dim(3))]};
                        app.TextArea_2.Value=str;
                        is_direxist(app);
                    else
                        app.computing_state = load_run_state.load;
                        str1={'','','','','','Load failed ! Please try again !'};
                        app.TextArea_2.Visible="on";
                        app.TextArea_2.Value=str1;
                        %app.TextArea_2.FontColor=[1.00,0,0];
                        app.TextArea_2.HorizontalAlignment="center";
                        return
                    end
                    
                end
            end

        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if  app.computing_state == load_run_state.run_or_load
                app.computing_state = load_run_state.nothing;
                app.TextArea_2.Value = "Computing..."; 
                app.TextArea_2.FontSize = 20;
                
    %           读取当前窗口大小
                WX=app.TabGroup.Position(1,3);
                WY=app.TabGroup.Position(1,4);   
                app.Image.Position=[WX/2-150,WY/2-75,300,150];
                app.Image.Visible="on";
               [im1,im2,im3]=im_path(app);
               render_result(app,im1,im2,im3,WX,WY);
               
            end
           
           
        end

        % Value changed function: SigninButton
        function SigninButtonValueChanged(app, event)
           ZH=app.zhanghao.Value;
           MM=app.mima.Value;
           
           if 1 %ZH=="cgy"&&MM=="608"
            app.message.Text="";
            value = app.SigninButton.Value;
            app.Labelzhanghao.Visible="off";
            app.Labelmima.Visible="off";
            app.zhanghao.Visible="off";
            app.mima.Visible="off";
            app.SigninButton.Visible="off";
            app.LeftPanel.Visible="on";
            app.RightPanel.Visible="on";
            
          else
            app.message.Text="Incorrect username or password!"
            return
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Glucose_HypoMetabolism';
            app.ImageAxes_1 = uiaxes(app.Tab1);
            app.ImageAxes_1.XTick = [];
            app.ImageAxes_1.XTickLabel = {'[ ]'};
            app.ImageAxes_1.YTick = [];
            app.ImageAxes_1.Visible = 'off';
            app.TextArea_2 = uitextarea(app.Tab1);
            app.TextArea_2.Visible = 'off';
            app.Image = uiimage(app.Tab1);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'waiting.gif';
            app.hypometabolismTab = uitab(app.TabGroup);
            app.hypometabolismTab.Title = 'hypometabolism            ';
            app.ImageAxes_2 = uiaxes(app.hypometabolismTab);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.ImageAxes_2.Visible = 'off';
            app.projADTab = uitab(app.TabGroup);
            app.projADTab.Title = 'projAD                          ';
            app.ImageAxes_3 = uiaxes(app.projADTab);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = {'[ ]'};
            app.ImageAxes_3.YTick = [];
            app.ImageAxes_3.Visible = 'off';
            app.Label = uilabel(app.projADTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.projADTab);
            app.TextArea.Visible = 'off';
            app.LeftPanel = uipanel(app.UIFigure);
            app.LoadFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'LoadFile';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Labelzhanghao = uilabel(app.UIFigure);
            app.Labelzhanghao.Text = 'Username:';
            app.Labelmima = uilabel(app.UIFigure);
            app.Labelmima.Text = 'Password:';
            app.SigninButton = uibutton(app.UIFigure, 'state');
            app.SigninButton.ValueChangedFcn = createCallbackFcn(app, @SigninButtonValueChanged, true);
            app.SigninButton.Text = 'Sign in';
            app.zhanghao = uieditfield(app.UIFigure, 'text');
            app.mima = uieditfield(app.UIFigure, 'text');
            app.message = uilabel(app.UIFigure);
            app.message.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI4_for_gy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end