classdef app2_autoreflow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        LeftPanel       matlab.ui.container.Panel
        LoadfileButton  matlab.ui.control.Button
        RightPanel      matlab.ui.container.Panel
        TabGroup        matlab.ui.container.TabGroup
        Tab             matlab.ui.container.Tab
        RunButton_tab1  matlab.ui.control.Button
        UIAxes          matlab.ui.control.UIAxes
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes_3        matlab.ui.control.UIAxes
        UIAxes_4        matlab.ui.control.UIAxes
        UIAxes_5        matlab.ui.control.UIAxes
        UIAxes_6        matlab.ui.control.UIAxes
        UIAxes_7        matlab.ui.control.UIAxes
        UIAxes_8        matlab.ui.control.UIAxes
        UIAxes_9        matlab.ui.control.UIAxes
        UIAxes_10       matlab.ui.control.UIAxes
        UIAxes_11       matlab.ui.control.UIAxes
        UIAxes_12       matlab.ui.control.UIAxes
        UIAxes_13       matlab.ui.control.UIAxes
        UIAxes_14       matlab.ui.control.UIAxes
        UIAxes_15       matlab.ui.control.UIAxes
        UIAxes_16       matlab.ui.control.UIAxes
        UIAxes_17       matlab.ui.control.UIAxes
        UIAxes_18       matlab.ui.control.UIAxes
        UIAxes_19       matlab.ui.control.UIAxes
        UIAxes_20       matlab.ui.control.UIAxes
        UIAxes_21       matlab.ui.control.UIAxes
        UIAxes_22       matlab.ui.control.UIAxes
        UIAxes_23       matlab.ui.control.UIAxes
        UIAxes_24       matlab.ui.control.UIAxes
        UIAxes_25       matlab.ui.control.UIAxes
        UIAxes_26       matlab.ui.control.UIAxes
        UIAxes_27       matlab.ui.control.UIAxes
        UIAxes_28       matlab.ui.control.UIAxes
        UIAxes_29       matlab.ui.control.UIAxes
        UIAxes_30       matlab.ui.control.UIAxes
        Tab2            matlab.ui.container.Tab
        RunButton_tab2  matlab.ui.control.Button
        UIAxes_31       matlab.ui.control.UIAxes
        UIAxes_32       matlab.ui.control.UIAxes
        UIAxes_33       matlab.ui.control.UIAxes
        UIAxes_34       matlab.ui.control.UIAxes
        UIAxes_35       matlab.ui.control.UIAxes
        UIAxes_36       matlab.ui.control.UIAxes
        UIAxes_37       matlab.ui.control.UIAxes
        UIAxes_38       matlab.ui.control.UIAxes
        UIAxes_39       matlab.ui.control.UIAxes
        UIAxes_40       matlab.ui.control.UIAxes
        UIAxes_41       matlab.ui.control.UIAxes
        UIAxes_42       matlab.ui.control.UIAxes
        UIAxes_43       matlab.ui.control.UIAxes
        UIAxes_44       matlab.ui.control.UIAxes
        UIAxes_45       matlab.ui.control.UIAxes
        UIAxes_46       matlab.ui.control.UIAxes
        UIAxes_47       matlab.ui.control.UIAxes
        UIAxes_48       matlab.ui.control.UIAxes
        UIAxes_49       matlab.ui.control.UIAxes
        UIAxes_50       matlab.ui.control.UIAxes
        UIAxes_51       matlab.ui.control.UIAxes
        UIAxes_52       matlab.ui.control.UIAxes
        UIAxes_53       matlab.ui.control.UIAxes
        UIAxes_54       matlab.ui.control.UIAxes
        UIAxes_55       matlab.ui.control.UIAxes
        UIAxes_56       matlab.ui.control.UIAxes
        UIAxes_57       matlab.ui.control.UIAxes
        UIAxes_58       matlab.ui.control.UIAxes
        UIAxes_59       matlab.ui.control.UIAxes
        UIAxes_60       matlab.ui.control.UIAxes
        Tab3            matlab.ui.container.Tab
        RunButton       matlab.ui.control.Button
        TextAreaLabel   matlab.ui.control.Label
        TextArea        matlab.ui.control.TextArea
        UIAxes2         matlab.ui.control.UIAxes
        UIAxes2_2       matlab.ui.control.UIAxes
        UIAxes2_3       matlab.ui.control.UIAxes
        UIAxes2_4       matlab.ui.control.UIAxes
        Tab4            matlab.ui.container.Tab
        RunButton_2     matlab.ui.control.Button
        UIAxes2_6       matlab.ui.control.UIAxes
        UIAxes2_7       matlab.ui.control.UIAxes
        UIAxes2_8       matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        loadfile % Description
    end
    
    methods (Access = public)
        
        function choise(app,source)
             im=dicomread(source);
          
              for i=1:30
                s = app.Tab.Children(i,1);
           
             s.Visible = 'off';
            s.Colormap = gray(256);
            imagesc(s, im);
              end
        end
        
        
        function choise3(app,source)
             im=dicomread(source);
          
%               for i=2:4
                s = app.Tab3.Children(3,1);
           
             s.Visible = 'off';
            s.Colormap = gray(256);
            imagesc(s, im);
            app.TextArea.Value="sss";
            
        end
        
        function choise4(app,source)
            im=dicomread(source);
          
%               for i=2:4
                s = app.Tab4.Children(3,1);
           
             s.Visible = 'off';
            s.Colormap = gray(256);
            imagesc(s, im);
            
        end
    end
    
    methods (Access = public)
        
        function choise2(app,source)
             im=dicomread(source);
          
              for i=1:30
                s = app.Tab2.Children(i,1);
           
             s.Visible = 'off';
            s.Colormap = gray(256);
            imagesc(s, im);
              end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadfileButton
        function LoadfileButtonPushed(app, event)
            filterspec = {'*.dcm','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if (ischar(p))
               
            app.loadfile = [p f];
            end
        end

        % Button pushed function: RunButton_tab1
        function RunButton_tab1Pushed(app, event)
            if (ischar(app.loadfile))
            choise(app,app.loadfile)
            else
                return
            end
        end

        % Button pushed function: RunButton_tab2
        function RunButton_tab2Pushed(app, event)
              if (ischar(app.loadfile))
            choise2(app,app.loadfile)
            else
                return
              end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            if (ischar(app.loadfile))
            choise3(app,app.loadfile)
            else
                return
            end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            if (ischar(app.loadfile))
            choise4(app,app.loadfile)
            else
                return
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {88, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.LoadfileButton = uibutton(app.LeftPanel, 'push');
            app.LoadfileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfileButtonPushed, true);
            app.LoadfileButton.Text = 'Load file';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.RunButton_tab1 = uibutton(app.Tab, 'push');
            app.RunButton_tab1.ButtonPushedFcn = createCallbackFcn(app, @RunButton_tab1Pushed, true);
            app.RunButton_tab1.Text = 'Run';
            app.UIAxes = uiaxes(app.Tab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.Tab);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.Tab);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.Tab);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.Tab);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.Tab);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_7 = uiaxes(app.Tab);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.Tab);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_9 = uiaxes(app.Tab);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.Tab);
            title(app.UIAxes_10, '')
            xlabel(app.UIAxes_10, '')
            ylabel(app.UIAxes_10, '')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.Tab);
            title(app.UIAxes_11, '')
            xlabel(app.UIAxes_11, '')
            ylabel(app.UIAxes_11, '')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.Tab);
            title(app.UIAxes_12, '')
            xlabel(app.UIAxes_12, '')
            ylabel(app.UIAxes_12, '')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_13 = uiaxes(app.Tab);
            title(app.UIAxes_13, '')
            xlabel(app.UIAxes_13, '')
            ylabel(app.UIAxes_13, '')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.Tab);
            title(app.UIAxes_14, '')
            xlabel(app.UIAxes_14, '')
            ylabel(app.UIAxes_14, '')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.Tab);
            title(app.UIAxes_15, '')
            xlabel(app.UIAxes_15, '')
            ylabel(app.UIAxes_15, '')
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.UIAxes_16 = uiaxes(app.Tab);
            title(app.UIAxes_16, '')
            xlabel(app.UIAxes_16, '')
            ylabel(app.UIAxes_16, '')
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_17 = uiaxes(app.Tab);
            title(app.UIAxes_17, '')
            xlabel(app.UIAxes_17, '')
            ylabel(app.UIAxes_17, '')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.UIAxes_18 = uiaxes(app.Tab);
            title(app.UIAxes_18, '')
            xlabel(app.UIAxes_18, '')
            ylabel(app.UIAxes_18, '')
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.UIAxes_19 = uiaxes(app.Tab);
            title(app.UIAxes_19, '')
            xlabel(app.UIAxes_19, '')
            ylabel(app.UIAxes_19, '')
            app.UIAxes_19.XTick = [];
            app.UIAxes_19.YTick = [];
            app.UIAxes_20 = uiaxes(app.Tab);
            title(app.UIAxes_20, '')
            xlabel(app.UIAxes_20, '')
            ylabel(app.UIAxes_20, '')
            app.UIAxes_20.XTick = [];
            app.UIAxes_20.YTick = [];
            app.UIAxes_21 = uiaxes(app.Tab);
            title(app.UIAxes_21, '')
            xlabel(app.UIAxes_21, '')
            ylabel(app.UIAxes_21, '')
            app.UIAxes_21.XTick = [];
            app.UIAxes_21.YTick = [];
            app.UIAxes_22 = uiaxes(app.Tab);
            title(app.UIAxes_22, '')
            xlabel(app.UIAxes_22, '')
            ylabel(app.UIAxes_22, '')
            app.UIAxes_22.XTick = [];
            app.UIAxes_22.YTick = [];
            app.UIAxes_23 = uiaxes(app.Tab);
            title(app.UIAxes_23, '')
            xlabel(app.UIAxes_23, '')
            ylabel(app.UIAxes_23, '')
            app.UIAxes_23.XTick = [];
            app.UIAxes_23.YTick = [];
            app.UIAxes_24 = uiaxes(app.Tab);
            title(app.UIAxes_24, '')
            xlabel(app.UIAxes_24, '')
            ylabel(app.UIAxes_24, '')
            app.UIAxes_24.XTick = [];
            app.UIAxes_24.YTick = [];
            app.UIAxes_25 = uiaxes(app.Tab);
            title(app.UIAxes_25, '')
            xlabel(app.UIAxes_25, '')
            ylabel(app.UIAxes_25, '')
            app.UIAxes_25.XTick = [];
            app.UIAxes_25.YTick = [];
            app.UIAxes_26 = uiaxes(app.Tab);
            title(app.UIAxes_26, '')
            xlabel(app.UIAxes_26, '')
            ylabel(app.UIAxes_26, '')
            app.UIAxes_26.XTick = [];
            app.UIAxes_26.YTick = [];
            app.UIAxes_27 = uiaxes(app.Tab);
            title(app.UIAxes_27, '')
            xlabel(app.UIAxes_27, '')
            ylabel(app.UIAxes_27, '')
            app.UIAxes_27.XTick = [];
            app.UIAxes_27.YTick = [];
            app.UIAxes_28 = uiaxes(app.Tab);
            title(app.UIAxes_28, '')
            xlabel(app.UIAxes_28, '')
            ylabel(app.UIAxes_28, '')
            app.UIAxes_28.XTick = [];
            app.UIAxes_28.YTick = [];
            app.UIAxes_29 = uiaxes(app.Tab);
            title(app.UIAxes_29, '')
            xlabel(app.UIAxes_29, '')
            ylabel(app.UIAxes_29, '')
            app.UIAxes_29.XTick = [];
            app.UIAxes_29.YTick = [];
            app.UIAxes_30 = uiaxes(app.Tab);
            title(app.UIAxes_30, '')
            xlabel(app.UIAxes_30, '')
            ylabel(app.UIAxes_30, '')
            app.UIAxes_30.XTick = [];
            app.UIAxes_30.YTick = [];
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.RunButton_tab2 = uibutton(app.Tab2, 'push');
            app.RunButton_tab2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_tab2Pushed, true);
            app.RunButton_tab2.Text = 'Run';
            app.UIAxes_31 = uiaxes(app.Tab2);
            title(app.UIAxes_31, '')
            xlabel(app.UIAxes_31, '')
            ylabel(app.UIAxes_31, '')
            app.UIAxes_31.XTick = [];
            app.UIAxes_31.YTick = [];
            app.UIAxes_31.YTickLabel = '';
            app.UIAxes_32 = uiaxes(app.Tab2);
            title(app.UIAxes_32, '')
            xlabel(app.UIAxes_32, '')
            ylabel(app.UIAxes_32, '')
            app.UIAxes_32.XTick = [];
            app.UIAxes_32.YTick = [];
            app.UIAxes_33 = uiaxes(app.Tab2);
            title(app.UIAxes_33, '')
            xlabel(app.UIAxes_33, '')
            ylabel(app.UIAxes_33, '')
            app.UIAxes_33.XTick = [];
            app.UIAxes_33.YTick = [];
            app.UIAxes_34 = uiaxes(app.Tab2);
            title(app.UIAxes_34, '')
            xlabel(app.UIAxes_34, '')
            ylabel(app.UIAxes_34, '')
            app.UIAxes_34.XTick = [];
            app.UIAxes_34.YTick = [];
            app.UIAxes_35 = uiaxes(app.Tab2);
            title(app.UIAxes_35, '')
            xlabel(app.UIAxes_35, '')
            ylabel(app.UIAxes_35, '')
            app.UIAxes_35.XTick = [];
            app.UIAxes_35.YTick = [];
            app.UIAxes_36 = uiaxes(app.Tab2);
            title(app.UIAxes_36, '')
            xlabel(app.UIAxes_36, '')
            ylabel(app.UIAxes_36, '')
            app.UIAxes_36.XTick = [];
            app.UIAxes_36.YTick = [];
            app.UIAxes_37 = uiaxes(app.Tab2);
            title(app.UIAxes_37, '')
            xlabel(app.UIAxes_37, '')
            ylabel(app.UIAxes_37, '')
            app.UIAxes_37.XTick = [];
            app.UIAxes_37.YTick = [];
            app.UIAxes_38 = uiaxes(app.Tab2);
            title(app.UIAxes_38, '')
            xlabel(app.UIAxes_38, '')
            ylabel(app.UIAxes_38, '')
            app.UIAxes_38.XTick = [];
            app.UIAxes_38.YTick = [];
            app.UIAxes_39 = uiaxes(app.Tab2);
            title(app.UIAxes_39, '')
            xlabel(app.UIAxes_39, '')
            ylabel(app.UIAxes_39, '')
            app.UIAxes_39.XTick = [];
            app.UIAxes_39.YTick = [];
            app.UIAxes_40 = uiaxes(app.Tab2);
            title(app.UIAxes_40, '')
            xlabel(app.UIAxes_40, '')
            ylabel(app.UIAxes_40, '')
            app.UIAxes_40.XTick = [];
            app.UIAxes_40.YTick = [];
            app.UIAxes_41 = uiaxes(app.Tab2);
            title(app.UIAxes_41, '')
            xlabel(app.UIAxes_41, '')
            ylabel(app.UIAxes_41, '')
            app.UIAxes_41.XTick = [];
            app.UIAxes_41.YTick = [];
            app.UIAxes_42 = uiaxes(app.Tab2);
            title(app.UIAxes_42, '')
            xlabel(app.UIAxes_42, '')
            ylabel(app.UIAxes_42, '')
            app.UIAxes_42.XTick = [];
            app.UIAxes_42.YTick = [];
            app.UIAxes_43 = uiaxes(app.Tab2);
            title(app.UIAxes_43, '')
            xlabel(app.UIAxes_43, '')
            ylabel(app.UIAxes_43, '')
            app.UIAxes_43.XTick = [];
            app.UIAxes_43.YTick = [];
            app.UIAxes_44 = uiaxes(app.Tab2);
            title(app.UIAxes_44, '')
            xlabel(app.UIAxes_44, '')
            ylabel(app.UIAxes_44, '')
            app.UIAxes_44.XTick = [];
            app.UIAxes_44.YTick = [];
            app.UIAxes_45 = uiaxes(app.Tab2);
            title(app.UIAxes_45, '')
            xlabel(app.UIAxes_45, '')
            ylabel(app.UIAxes_45, '')
            app.UIAxes_45.XTick = [];
            app.UIAxes_45.YTick = [];
            app.UIAxes_46 = uiaxes(app.Tab2);
            title(app.UIAxes_46, '')
            xlabel(app.UIAxes_46, '')
            ylabel(app.UIAxes_46, '')
            app.UIAxes_46.XTick = [];
            app.UIAxes_46.YTick = [];
            app.UIAxes_47 = uiaxes(app.Tab2);
            title(app.UIAxes_47, '')
            xlabel(app.UIAxes_47, '')
            ylabel(app.UIAxes_47, '')
            app.UIAxes_47.XTick = [];
            app.UIAxes_47.YTick = [];
            app.UIAxes_48 = uiaxes(app.Tab2);
            title(app.UIAxes_48, '')
            xlabel(app.UIAxes_48, '')
            ylabel(app.UIAxes_48, '')
            app.UIAxes_48.XTick = [];
            app.UIAxes_48.YTick = [];
            app.UIAxes_49 = uiaxes(app.Tab2);
            title(app.UIAxes_49, '')
            xlabel(app.UIAxes_49, '')
            ylabel(app.UIAxes_49, '')
            app.UIAxes_49.XTick = [];
            app.UIAxes_49.YTick = [];
            app.UIAxes_50 = uiaxes(app.Tab2);
            title(app.UIAxes_50, '')
            xlabel(app.UIAxes_50, '')
            ylabel(app.UIAxes_50, '')
            app.UIAxes_50.XTick = [];
            app.UIAxes_50.YTick = [];
            app.UIAxes_51 = uiaxes(app.Tab2);
            title(app.UIAxes_51, '')
            xlabel(app.UIAxes_51, '')
            ylabel(app.UIAxes_51, '')
            app.UIAxes_51.XTick = [];
            app.UIAxes_51.YTick = [];
            app.UIAxes_52 = uiaxes(app.Tab2);
            title(app.UIAxes_52, '')
            xlabel(app.UIAxes_52, '')
            ylabel(app.UIAxes_52, '')
            app.UIAxes_52.XTick = [];
            app.UIAxes_52.YTick = [];
            app.UIAxes_53 = uiaxes(app.Tab2);
            title(app.UIAxes_53, '')
            xlabel(app.UIAxes_53, '')
            ylabel(app.UIAxes_53, '')
            app.UIAxes_53.XTick = [];
            app.UIAxes_53.YTick = [];
            app.UIAxes_54 = uiaxes(app.Tab2);
            title(app.UIAxes_54, '')
            xlabel(app.UIAxes_54, '')
            ylabel(app.UIAxes_54, '')
            app.UIAxes_54.XTick = [];
            app.UIAxes_54.YTick = [];
            app.UIAxes_55 = uiaxes(app.Tab2);
            title(app.UIAxes_55, '')
            xlabel(app.UIAxes_55, '')
            ylabel(app.UIAxes_55, '')
            app.UIAxes_55.XTick = [];
            app.UIAxes_55.YTick = [];
            app.UIAxes_56 = uiaxes(app.Tab2);
            title(app.UIAxes_56, '')
            xlabel(app.UIAxes_56, '')
            ylabel(app.UIAxes_56, '')
            app.UIAxes_56.XTick = [];
            app.UIAxes_56.YTick = [];
            app.UIAxes_57 = uiaxes(app.Tab2);
            title(app.UIAxes_57, '')
            xlabel(app.UIAxes_57, '')
            ylabel(app.UIAxes_57, '')
            app.UIAxes_57.XTick = [];
            app.UIAxes_57.YTick = [];
            app.UIAxes_58 = uiaxes(app.Tab2);
            title(app.UIAxes_58, '')
            xlabel(app.UIAxes_58, '')
            ylabel(app.UIAxes_58, '')
            app.UIAxes_58.XTick = [];
            app.UIAxes_58.YTick = [];
            app.UIAxes_59 = uiaxes(app.Tab2);
            title(app.UIAxes_59, '')
            xlabel(app.UIAxes_59, '')
            ylabel(app.UIAxes_59, '')
            app.UIAxes_59.XTick = [];
            app.UIAxes_59.YTick = [];
            app.UIAxes_60 = uiaxes(app.Tab2);
            title(app.UIAxes_60, '')
            xlabel(app.UIAxes_60, '')
            ylabel(app.UIAxes_60, '')
            app.UIAxes_60.XTick = [];
            app.UIAxes_60.YTick = [];
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.RunButton = uibutton(app.Tab3, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TextAreaLabel = uilabel(app.Tab3);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.Tab3);
            app.UIAxes2 = uiaxes(app.Tab3);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes2_2 = uiaxes(app.Tab3);
            title(app.UIAxes2_2, '')
            xlabel(app.UIAxes2_2, '')
            ylabel(app.UIAxes2_2, '')
            app.UIAxes2_2.XTick = [];
            app.UIAxes2_2.YTick = [];
            app.UIAxes2_3 = uiaxes(app.Tab3);
            title(app.UIAxes2_3, '')
            xlabel(app.UIAxes2_3, '')
            ylabel(app.UIAxes2_3, '')
            app.UIAxes2_3.XTick = [];
            app.UIAxes2_3.YTick = [];
            app.UIAxes2_4 = uiaxes(app.Tab3);
            title(app.UIAxes2_4, '')
            xlabel(app.UIAxes2_4, '')
            ylabel(app.UIAxes2_4, '')
            app.UIAxes2_4.XTick = [];
            app.UIAxes2_4.YTick = [];
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.RunButton_2 = uibutton(app.Tab4, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Text = 'Run';
            app.UIAxes2_6 = uiaxes(app.Tab4);
            title(app.UIAxes2_6, '')
            xlabel(app.UIAxes2_6, '')
            ylabel(app.UIAxes2_6, '')
            app.UIAxes2_6.XTick = [];
            app.UIAxes2_6.YTick = [];
            app.UIAxes2_7 = uiaxes(app.Tab4);
            title(app.UIAxes2_7, '')
            xlabel(app.UIAxes2_7, '')
            ylabel(app.UIAxes2_7, '')
            app.UIAxes2_7.XTick = [];
            app.UIAxes2_7.YTick = [];
            app.UIAxes2_8 = uiaxes(app.Tab4);
            title(app.UIAxes2_8, '')
            xlabel(app.UIAxes2_8, '')
            ylabel(app.UIAxes2_8, '')
            app.UIAxes2_8.XTick = [];
            app.UIAxes2_8.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2_autoreflow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end