classdef GUI2_for_gy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        RightPanel         matlab.ui.container.Panel
        TabGroup           matlab.ui.container.TabGroup
        Tab1               matlab.ui.container.Tab
        ImageAxes_1        matlab.ui.control.UIAxes
        TextArea_2         matlab.ui.control.TextArea
        hypometabolismTab  matlab.ui.container.Tab
        ImageAxes_2        matlab.ui.control.UIAxes
        projADTab          matlab.ui.container.Tab
        ImageAxes_3        matlab.ui.control.UIAxes
        Label              matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        LeftPanel          matlab.ui.container.Panel
        LoadFileButton     matlab.ui.control.Button
        RunButton          matlab.ui.control.Button
        Labelzhanghao      matlab.ui.control.Label
        Labelmima          matlab.ui.control.Label
        SigninButton       matlab.ui.control.StateButton
        zhanghao           matlab.ui.control.EditField
        mima               matlab.ui.control.EditField
        message            matlab.ui.control.Label
    end

    
    properties (Access = public)
        loadfile % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FIG_size=app.UIFigure.Position;
            app.LeftPanel.Visible="off";
            app.RightPanel.Visible="off";
            WXfig=app.UIFigure.Position(1,3);
            WYfig=app.UIFigure.Position(1,4);
            app.zhanghao.Position=[round(WXfig/2-100),round(WYfig/2),200,30];
            app.Labelzhanghao.Position=[round(WXfig/2-180),round(WYfig/2),200,30];
            app.mima.Position=[round(WXfig/2-100),round(WYfig/2-50),200,30];
            app.Labelmima.Position=[round(WXfig/2-180),round(WYfig/2-50),200,30];
            app.SigninButton.Position=[round(WXfig/2-60),round(WYfig/2-100),120,30];
            app.message.Position=[round(WXfig/2-160),round(WYfig/2+50),300,30];
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            WXload=app.TabGroup.Position(1,3);
            WYload=app.TabGroup.Position(1,4);
            app.TextArea_2.Position=[WXload/2-150,WYload/2-100,300,200];
          
            
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.dcm','All Image Files'};
            [f, p] = uigetfile(filterspec);
            if (ischar(p))
            app.loadfile = [p f];
            info=dicominfo("D:\GUI\10104.dcm");
            app.TextArea_2.Visible="on";
            app.TextArea_2.FontColor=[0.00,0,0];
            app.TextArea_2.HorizontalAlignment="left";
            str= {'Successfully !', 'Filename:' ,info.Filename,'PatientName:',info.PatientName.FamilyName,'PatientID:',info.PatientID,}
              app.TextArea_2.Value=str;
%               "Filename:" + info.Filename; 
             
            else
                str1={'','','','','','Faild ! Please try again !'}
                   app.TextArea_2.Visible="on";
                app.TextArea_2.Value=str1;
                app.TextArea_2.FontColor=[1.00,0,0];
                app.TextArea_2.HorizontalAlignment="center";
                return
            
           
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
             
            app.TextArea_2.Visible="off";
%            pause(20);
            im3=imread("D:\GUI\projAD.tif");
            im1=imread("D:\GUI\Glucose_HypoMetabolism.tif");
            im2=imread("D:\GUI\hypometabolism.tif");
            SIZE_axes1=size(im1);
            X_axes1=SIZE_axes1(1,1);
            Y_axes1=SIZE_axes1(1,2);
            SIZE_axes2=size(im2);
            X_axes2=SIZE_axes2(1,1);
            Y_axes2=SIZE_axes2(1,2);
            SIZE_axes3=size(im1);
            X_axes3=SIZE_axes1(1,1);
            Y_axes3=SIZE_axes1(1,2);
            app.ImageAxes_1.Visible="off";
            app.ImageAxes_2.Visible="off";
            app.ImageAxes_3.Visible="off";
            axis(app.ImageAxes_1, 'image');
            axis(app.ImageAxes_2, 'image');
            axis(app.ImageAxes_3, 'image');
            imagesc(app.ImageAxes_1,im1);
            imagesc(app.ImageAxes_2,im2);
            imagesc(app.ImageAxes_3,im3);
           
%           读取当前窗口大小
            WX=app.TabGroup.Position(1,3);
            WY=app.TabGroup.Position(1,4);
%            设置axes位置和大小
           app.ImageAxes_1.Position=[round(WX/2-((Y_axes1*4*WY/5)/X_axes1)/2),round(WY/2-2*WY/5),round((Y_axes1*4*WY/5)/X_axes1),4*WY/5];
%           禁止交互， enableDefaultInteractivity重新启用交互
           disableDefaultInteractivity(app.ImageAxes_1) ;
           app.ImageAxes_1.Toolbar.Visible="off";
           
%            设置axes位置和大小
           app.ImageAxes_2.Position=[round(WX/2-((Y_axes2*4*WY/5)/X_axes2)/2),round(WY/2-2*WY/5),round((Y_axes2*4*WY/5)/X_axes2),4*WY/5];
%           禁止交互， enableDefaultInteractivity重新启用交互
           disableDefaultInteractivity(app.ImageAxes_2) ;
           app.ImageAxes_2.Toolbar.Visible="off";
           
%            设置axes位置和大小
           app.ImageAxes_3.Position=[round(2*WX/3-((Y_axes3*4*WY/5)/X_axes3)/2),round(WY/2-2*WY/5),round((Y_axes3*4*WY/5)/X_axes3),4*WY/5];
%            设置文本框位置和大小
           app.TextArea.Position=[round(2*WX/3-((Y_axes3*4*WY/5)/X_axes3)/2-300),round(WY/2-300),round(400),600];
           app.TextArea.Value="this is a text";
%           禁止交互， enableDefaultInteractivity重新启用交互
           disableDefaultInteractivity(app.ImageAxes_3) ;
           app.ImageAxes_3.Toolbar.Visible="off";
           
           
        end

        % Value changed function: SigninButton
        function SigninButtonValueChanged(app, event)
           ZH=app.zhanghao.Value;
           MM=app.mima.Value;
           
           if ZH=="cgy"&&MM=="608"
            app.message.Text="";
            value = app.SigninButton.Value;
            app.Labelzhanghao.Visible="off";
            app.Labelmima.Visible="off";
            app.zhanghao.Visible="off";
            app.mima.Visible="off";
            app.SigninButton.Visible="off";
            app.LeftPanel.Visible="on";
            app.RightPanel.Visible="on";
            
          else
            app.message.Text="Incorrect username or password!"
            return
           end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.RightPanel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Glucose_HypoMetabolism';
            app.ImageAxes_1 = uiaxes(app.Tab1);
            app.ImageAxes_1.XTick = [];
            app.ImageAxes_1.XTickLabel = {'[ ]'};
            app.ImageAxes_1.YTick = [];
            app.TextArea_2 = uitextarea(app.Tab1);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Visible = 'off';
            app.hypometabolismTab = uitab(app.TabGroup);
            app.hypometabolismTab.Title = 'hypometabolism            ';
            app.ImageAxes_2 = uiaxes(app.hypometabolismTab);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            app.projADTab = uitab(app.TabGroup);
            app.projADTab.Title = 'projAD                          ';
            app.ImageAxes_3 = uiaxes(app.projADTab);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = {'[ ]'};
            app.ImageAxes_3.YTick = [];
            app.Label = uilabel(app.projADTab);
            app.Label.Text = '';
            app.TextArea = uitextarea(app.projADTab);
            app.LeftPanel = uipanel(app.UIFigure);
            app.LoadFileButton = uibutton(app.LeftPanel, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'LoadFile';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Labelzhanghao = uilabel(app.UIFigure);
            app.Labelzhanghao.Text = 'Username:';
            app.Labelmima = uilabel(app.UIFigure);
            app.Labelmima.Text = 'Password:';
            app.SigninButton = uibutton(app.UIFigure, 'state');
            app.SigninButton.ValueChangedFcn = createCallbackFcn(app, @SigninButtonValueChanged, true);
            app.SigninButton.Text = 'Sign in';
            app.zhanghao = uieditfield(app.UIFigure, 'text');
            app.mima = uieditfield(app.UIFigure, 'text');
            app.message = uilabel(app.UIFigure);
            app.message.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI2_for_gy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end